classdef MovieViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MovieViewerUIFigure   matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        OpenMenu              matlab.ui.container.Menu
        ExportmovieAVIMenu    matlab.ui.container.Menu
        FrameLabel            matlab.ui.control.Label
        FrameSlider           matlab.ui.control.Slider
        UIAxes                matlab.ui.control.UIAxes
        FrameLowerLimitLabel  matlab.ui.control.Label
        FrameUpperLimitLabel  matlab.ui.control.Label
        PlayButton            matlab.ui.control.Button
        Label                 matlab.ui.control.Label
        DrawNowDropDown       matlab.ui.control.DropDown
    end


    properties (Access = private)

        
    end

    properties (Access = public)
        localOrRemote
        mouse
        date
        expnum
        sesDir
        frameData   % Faster than calling squeeze repeatedly on a 3D array.
        frameInfo
        movieOpener
        numFrames = 1
        startFrame
        stopFrame
        im
        curFrame = int32(-1)  % Necessary because the frame slider emits doubles (not integers).
        frameRate = 30
        moviePlaying = false  % State variable.
        movieTimer;

        
    end

    methods (Access = public)
    
        function sessionChosen(app)
            
            if app.movieOpener.cancelled
                delete(app.movieOpener)
                app.movieOpener = [];
                return
            end
            
            % Extract info from movie opener, and delete it.
            app.localOrRemote = app.movieOpener.LocationDropDown.Value;
            app.mouse = app.movieOpener.MouseDropDown.Value;
            app.date = app.movieOpener.DateDropDown.Value;
            app.expnum = app.movieOpener.SessionDropDown.Value;
            app.sesDir = sessiondir(app.mouse, app.date, app.expnum, app.localOrRemote);
            app.startFrame = app.movieOpener.startFrame;
            app.stopFrame = app.movieOpener.stopFrame;
            mov = app.movieOpener.mov;         
            app.numFrames = size(mov, 1);
            delete(app.movieOpener);
            app.movieOpener = [];
            
            % Load frame info if it exists.
            if exist(fullfile(app.sesDir, 'frameInfo.mat'), 'file')
                app.frameInfo = loadFrameInfo(app.mouse, app.date, ...
                                              app.expnum, app.localOrRemote);
                app.frameInfo = app.frameInfo(app.startFrame:app.stopFrame);
            else
                app.frameInfo = [];
            end
            
            % Rescale and smooth 'mov'.
            mov = double(mov);
            maxval = prctile(mov(:), 99);
            mov = mov / maxval;
            mov(mov > maxval) = maxval;
            mov = smooth3(mov, 'box', [5 1 1]);
            
            % Update slider and play button.
            app.FrameSlider.Limits = [double(app.startFrame), double(app.stopFrame)];
            app.FrameLowerLimitLabel.Text = num2str(app.startFrame);
            app.FrameUpperLimitLabel.Text = num2str(app.stopFrame);
            app.FrameSlider.Enable = 'on';
            app.FrameSlider.Value = double(app.startFrame);
            app.curFrame = int32(app.startFrame);
            app.FrameLabel.Text = ['Frame: ' num2str(app.curFrame)];
            app.PlayButton.Enable = 'on';
            
            % Convert 'mov' to frameData.
            app.frameData = struct('frameNumber', {}, 'data', {});
            counter = 1;
            for ii=app.startFrame:app.stopFrame                
                app.frameData(counter).data = squeeze(mov(ii, :, :));
                app.frameData(counter).frameNumber = counter;
                counter = counter + 1;
            end
            
            % Create Image object.
            app.im = imshow(app.frameData(1).data, [0 1], ...
                           'Parent', app.UIAxes, ...
                           'XData', [1 app.UIAxes.Position(3)], ...
                           'YData', [1 app.UIAxes.Position(4)]);

            % Set limits of axes
            app.UIAxes.XLim = [0 app.im.XData(2)];
            app.UIAxes.YLim = [0 app.im.YData(2)];
                        
            % Finally, draw the imagery.
            app.labelFrames();
            app.updateImage();     
            
        end
        
        function labelFrames(app)
            
            numFrames = length(app.frameData);
            numYPix = size(app.frameData(1), 2);
            numXPix = size(app.frameData(1), 3);
            frameData = struct('frameNumber', {}, 'data', {});
            
            seqSpecs = sequenceSpecs;
            eventValues = extractfield(app.frameInfo, 'EventValue');
            for ii=1:numFrames
                f = app.frameData(ii);
                frameData(ii).frameNumber = f.frameNumber;
                label = eventValueToLabel(eventValues(ii), seqSpecs);
                data = insertText(f.data, [10 10], label, 'FontSize', 32);
                frameData(ii).data = data;
            end
            app.frameData = frameData;
        end
        
        function updateImage(app)
            
            data = app.frameData(app.curFrame - app.startFrame + 1).data;
            app.im.CData = data;            
            if strcmp(app.DrawNowDropDown.Value, 'draw slow but smooth')
                drawnow;
            end
        end
        
        function incrementPlayer(app)
                        
            if app.curFrame == app.stopFrame
                app.FrameSlider.Value = app.startFrame;
            else
                app.FrameSlider.Value = app.FrameSlider.Value + 1;
            end
            app.FrameSliderValueChanged();
        end
        
        function chooseSessionManually(app, localOrRemote, mouse, date, expnum, ...
                                                    startFrame, stopFrame, mov)
            
                                                    
            % This is mostly a debugging function. It allows me to restart MovieViewer
            % without having to click and load datasets when I reopen it.
            app.localOrRemote = localOrRemote;
            app.mouse = mouse;
            app.date = date;
            app.expnum = expnum;
            app.sesDir = sessiondir(app.mouse, app.date, app.expnum, app.localOrRemote);
            app.startFrame = startFrame;
            app.stopFrame = stopFrame;
            mov = mov;
            app.movieOpener = [];            
            app.numFrames = size(mov, 1);
            
            % Load frame info if it exists.
            if exist(fullfile(app.sesDir, 'frameInfo.mat'), 'file')
                app.frameInfo = loadFrameInfo(app.mouse, app.date, ...
                                              app.expnum, app.localOrRemote);
                app.frameInfo = app.frameInfo(app.startFrame:app.stopFrame);
            else
                app.frameInfo = [];
            end                  
            
            % Modify 'mov'.
            mov = double(mov);
            maxval = prctile(mov(:), 99);
            mov = mov / maxval;
            mov(mov > maxval) = maxval;
            mov = smooth3(mov, 'box', [5 1 1]);
            
            % Update slider and play button.
            app.FrameSlider.Limits = [double(app.startFrame), double(app.stopFrame)];
            app.FrameLowerLimitLabel.Text = num2str(app.startFrame);
            app.FrameUpperLimitLabel.Text = num2str(app.stopFrame);
            app.FrameSlider.Enable = 'on';
            app.FrameSlider.Value = double(app.startFrame);
            app.curFrame = int32(app.startFrame);
            app.FrameLabel.Text = ['Frame: ' num2str(app.curFrame)];
            app.PlayButton.Enable = 'on';
            
            % Create frame data.
            app.frameData = struct('frameNumber', {}, 'data', {});
            counter = 1;
            for ii=app.startFrame:app.stopFrame
                app.frameData(counter).data = squeeze(mov(ii, :, :));
                app.frameData(counter).frameNumber = counter;
                counter = counter + 1;
            end
            
            % Create Image object.
            app.im = imshow(app.frameData(1).data, [0 1], ...
                           'Parent', app.UIAxes, ...
                           'XData', [1 app.UIAxes.Position(3)], ...
                           'YData', [1 app.UIAxes.Position(4)]);

            % Set limits of axes
            app.UIAxes.XLim = [0 app.im.XData(2)];
            app.UIAxes.YLim = [0 app.im.YData(2)];
                        
            % Finally, draw the imagery.
            app.labelFrames();
            app.updateImage();            
        end
                
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, manualArg)

            % Disable frame slider (and buttons) until data is loaded.
            app.FrameSlider.Enable = 'off';
            app.PlayButton.Enable = 'off';

            % Set up the timer.
            app.movieTimer = timer('ExecutionMode', 'fixedRate', ...
                                   'TasksToExecute', Inf, ...
                                   'BusyMode', 'queue', ...
                                   'Period', 0.033, ...
                                   'TimerFcn', @(~, ~)incrementPlayer(app));

            % Trigger automatic opening of 'OpenMovie' widget when not in manual/debugging mode.
            if nargin == 1
                app.OpenMenuSelected();
            end
            
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            if ~isa(app.movieOpener, 'MovieOpener')
                app.movieOpener = OpenMovie(app);
            end
        end

        % Close request function: MovieViewerUIFigure
        function MovieViewerUIFigureCloseRequest(app, event)
            if isa(app.movieOpener, 'OpenMovie')
                delete(app.movieOpener);
            end
            stop(app.movieTimer);
            delete(app)
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            value = int32(round(app.FrameSlider.Value));
            if value == app.curFrame
                return
            end
            app.curFrame = value;
            app.FrameLabel.Text = ['Frame: ' num2str(app.curFrame)];
            app.updateImage();
            
        end

        % Value changing function: FrameSlider
        function FrameSliderValueChanging(app, event)
            value = int32(round(event.Value));
            if value == app.curFrame
                return
            end
            app.curFrame = value;
            app.FrameLabel.Text = ['Frame: ' num2str(app.curFrame)];
            app.updateImage();
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if strcmp(app.PlayButton.Text, 'Play')
                app.PlayButton.Text = 'Pause';
                start(app.movieTimer);
            else
                app.PlayButton.Text = 'Play';
                stop(app.movieTimer);
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MovieViewerUIFigure = uifigure;
            app.MovieViewerUIFigure.Name = 'Movie Viewer';
            app.MovieViewerUIFigure.CloseRequestFcn = createCallbackFcn(app, @MovieViewerUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.MovieViewerUIFigure);
            app.FileMenu.Text = 'File';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Accelerator = 'o';
            app.OpenMenu.Text = 'Open';
            app.ExportmovieAVIMenu = uimenu(app.FileMenu);
            app.ExportmovieAVIMenu.Text = 'Export movie AVI';
            app.FrameLabel = uilabel(app.MovieViewerUIFigure);
            app.FrameLabel.Text = 'Frame:              ';
            app.FrameSlider = uislider(app.MovieViewerUIFigure);
            app.FrameSlider.Limits = [1 10];
            app.FrameSlider.MajorTicks = [1 10];
            app.FrameSlider.MajorTickLabels = {'', ''};
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.ValueChangingFcn = createCallbackFcn(app, @FrameSliderValueChanging, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Value = 1;
            app.UIAxes = uiaxes(app.MovieViewerUIFigure);
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.FrameLowerLimitLabel = uilabel(app.MovieViewerUIFigure);
            app.FrameLowerLimitLabel.Text = '-';
            app.FrameUpperLimitLabel = uilabel(app.MovieViewerUIFigure);
            app.FrameUpperLimitLabel.Text = '-';
            app.PlayButton = uibutton(app.MovieViewerUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.Label = uilabel(app.MovieViewerUIFigure);
            app.Label.Text = '';
            app.DrawNowDropDown = uidropdown(app.MovieViewerUIFigure);
            app.DrawNowDropDown.Items = {'draw fast but chunky', 'draw slow but smooth'};
            app.DrawNowDropDown.Value = 'draw fast but chunky';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MovieViewer(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MovieViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MovieViewerUIFigure)
        end
    end
end