classdef OpenMovie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectSessionUIFigure  matlab.ui.Figure
        MouseDropDownLabel     matlab.ui.control.Label
        MouseDropDown          matlab.ui.control.DropDown
        DateDropDownLabel      matlab.ui.control.Label
        DateDropDown           matlab.ui.control.DropDown
        SessionDropDownLabel   matlab.ui.control.Label
        SessionDropDown        matlab.ui.control.DropDown
        OpenButton             matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
        FramesEditFieldLabel   matlab.ui.control.Label
        FramesEditField        matlab.ui.control.EditField
        LocationDropDownLabel  matlab.ui.control.Label
        LocationDropDown       matlab.ui.control.DropDown
    end


    properties (Access = private)
        callingApp % Description
    end

    properties (Access = public)
        cancelled % Description
        frameArgs
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.LocationDropDown.Items = {'local', 'remote'};
            app.LocationDropDown.Value = 'local';            
            app.cancelled = false;
            app.callingApp = mainApp;
            app.MouseDropDown.Items = listMice();
            app.DateDropDown.Items = listDates(app.MouseDropDown.Value);
            app.SessionDropDown.Items = listExpNums(app.MouseDropDown.Value, ...
                                                    app.DateDropDown.Value);
            app.frameArgs = [];
        end

        % Value changed function: MouseDropDown
        function MouseDropDownValueChanged(app, event)

            app.DateDropDown.Items = listDates(app.MouseDropDown.Value, ...
                                               app.LocationDropDown.Value);
            app.SessionDropDown.Items = listExpNums(app.MouseDropDown.Value, ...
                                                    app.DateDropDown.Value, ...
                                                    app.LocationDropDown.Value);
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            app.SessionDropDown.Items = listExpNums(app.MouseDropDown.Value, ...
                                                    app.DateDropDown.Value, ...
                                                    app.LocationDropDown.Value);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Check that the 'frames' entry is well-formed, and set app.frameArgs.
            msg = 'Bad value in frames field. It can be left empty, ';
            msg = [msg 'be an integer, or a start:stop slice.'];
            
            framesSpec = app.FramesEditField.Value;
            if ~isempty(framesSpec)
                app.frameArgs = strsplit(framesSpec, ':');
                
                % Handle too many colons.
                if ~ismember(length(app.frameArgs), [1, 2])
                    errordlg('');
                end
                
                % One frame arg.
                if isempty(str2num(app.frameArgs{1}))
                    errordlg(msg)
                end
                app.frameArgs{1} = int32(str2num(app.frameArgs{1}));
                
                % Two frame args.
                if length(app.frameArgs) == 2
                    if isempty(str2num(app.frameArgs{2}))
                        errordlg(msg)
                    end
                    app.frameArgs{2} = int32(str2num(app.frameArgs{2}));
                end

                app.frameArgs = cell2mat(app.frameArgs);
            end
            app.OpenButton.Text = 'Loading...';
            app.OpenButton.Enable = 'off';
            app.CancelButton.Enable = 'off';
            app.callingApp.sessionChosen(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.cancelled = true;
            app.callingApp.sessionChosen(app);
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            app.MouseDropDown.Items = listMice(app.LocationDropDown.Value);
            app.DateDropDown.Items = listDates(app.MouseDropDown.Value, ...
                                               app.LocationDropDown.Value);
            app.SessionDropDown.Items = listExpNums(app.MouseDropDown.Value, ...
                                                    app.DateDropDown.Value, ...
                                                    app.LocationDropDown.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectSessionUIFigure = uifigure;
            app.SelectSessionUIFigure.Name = 'Select Session';
            app.MouseDropDownLabel = uilabel(app.SelectSessionUIFigure);
            app.MouseDropDownLabel.Text = 'Mouse';
            app.MouseDropDown = uidropdown(app.SelectSessionUIFigure);
            app.MouseDropDown.ValueChangedFcn = createCallbackFcn(app, @MouseDropDownValueChanged, true);
            app.DateDropDownLabel = uilabel(app.SelectSessionUIFigure);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.SelectSessionUIFigure);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.SessionDropDownLabel = uilabel(app.SelectSessionUIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.SelectSessionUIFigure);
            app.OpenButton = uibutton(app.SelectSessionUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CancelButton = uibutton(app.SelectSessionUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FramesEditFieldLabel = uilabel(app.SelectSessionUIFigure);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.SelectSessionUIFigure, 'text');
            app.LocationDropDownLabel = uilabel(app.SelectSessionUIFigure);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.SelectSessionUIFigure);
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = OpenMovie(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectSessionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectSessionUIFigure)
        end
    end
end