classdef OpenSession < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SelectSessionUIFigure  matlab.ui.Figure
        MouseDropDownLabel     matlab.ui.control.Label
        MouseDropDown          matlab.ui.control.DropDown
        DateDropDownLabel      matlab.ui.control.Label
        DateDropDown           matlab.ui.control.DropDown
        SessionDropDownLabel   matlab.ui.control.Label
        SessionDropDown        matlab.ui.control.DropDown
        OpenButton             matlab.ui.control.Button
        CancelButton           matlab.ui.control.Button
        FramesEditFieldLabel   matlab.ui.control.Label
        FramesEditField        matlab.ui.control.EditField
        LocationDropDownLabel  matlab.ui.control.Label
        LocationDropDown       matlab.ui.control.DropDown
    end


    properties (Access = private)

    end

    properties (Access = public)
        parentApp = []
        cancelled = false
        mov = []
        startFrame = []
        stopFrame = []
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            if nargin == 2
                app.parentApp = parentApp;
            end
            app.LocationDropDown.Items = {'local', 'remote'};
            app.LocationDropDown.Value = 'local';                     
            app.MouseDropDown.Items = listMice();
            app.DateDropDown.Items = listDates(app.MouseDropDown.Value);
            app.SessionDropDown.Items = listExpNums(app.MouseDropDown.Value, ...
                                                    app.DateDropDown.Value);
        end

        % Value changed function: MouseDropDown
        function MouseDropDownValueChanged(app, event)

            app.DateDropDown.Items = listDates(app.MouseDropDown.Value, ...
                                               app.LocationDropDown.Value);
            app.SessionDropDown.Items = listExpNums(app.MouseDropDown.Value, ...
                                                    app.DateDropDown.Value, ...
                                                    app.LocationDropDown.Value);
        end

        % Value changed function: DateDropDown
        function DateDropDownValueChanged(app, event)
            app.SessionDropDown.Items = listExpNums(app.MouseDropDown.Value, ...
                                                    app.DateDropDown.Value, ...
                                                    app.LocationDropDown.Value);
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            % Check that the 'frames' entry is well-formed, and set app.frameArgs.
            framesSpec = app.FramesEditField.Value;
            if isempty(framesSpec)
                frameArgs = [];
            else
                try
                
                    frameArgs = strsplit(framesSpec, ':');
                    
                    % Throw error if too many colons.
                    if ~ismember(length(frameArgs), [1, 2])
                        error('Too many semicolons.');
                    end
                    
                    % One frame arg. Throw error if not well-formed.
                    if isempty(str2num(frameArgs{1}))
                        error('First argument is malformed.')
                    end
                    frameArgs{1} = int32(str2num(frameArgs{1}));
                    
                    % Two frame args. Throw error if second is not well-formed.
                    if length(frameArgs) == 2
                        if isempty(str2num(frameArgs{2}))
                            error('Second argument is malformed.')
                        end
                        frameArgs{2} = int32(str2num(frameArgs{2}));
                    end
                    
                    % Frame args are good. Turn into vector and move on.
                    frameArgs = cell2mat(frameArgs);
                
                catch ME
                    msg = 'Bad value in frames field. It can be left empty, ';
                    msg = [msg 'be an integer, or a start:stop slice. '];          
                    msg = [msg ME.message];
                    errordlg(msg);
                    return
                end
            end        
            
            % Load movie.
            location = app.LocationDropDown.Value;
            mouse = app.MouseDropDown.Value;
            date = app.DateDropDown.Value;
            expnum = app.SessionDropDown.Value;
            sesDir = sessiondir(mouse, date, expnum, location);
            
            % Find what format the movie is in, and select the correct readers.
            if exist(fullfile(sesDir, 'mov'), 'dir')
                movLoc = fullfile(sesDir, 'mov');
                movInfo = tss_info(movLoc);
                readFunc = @tss_read;
            elseif exist(fullfile(sesDir, 'Image_0001_0001.raw'), 'file')
                movLoc = fullfile(sesDir, 'Image_0001_0001.raw');
                movInfo = raw_info(movLoc);
                readFunc = @raw_read;
            else
                errordlg('Cannot find a compatible movie.');
                return
            end
            
            % Figure out how to slice it.
            if isempty(frameArgs)
                [start, stop] = sanitizeSlice(1, movInfo.numFrames, movInfo.numFrames);
            elseif length(frameArgs) == 1
                [start, stop] = sanitizeSlice(1, frameArgs(1), movInfo.numFrames);
            else
                [start, stop] = sanitizeSlice(frameArgs(1), frameArgs(2), movInfo.numFrames); 
            end
            
            % Attempt to read the movie.
            try                
                app.OpenButton.Text = 'Loading...';
                app.OpenButton.Enable = 'off';
                app.CancelButton.Enable = 'off';
                mov = readFunc(movLoc, start, stop);
            catch ME
                msg = ['There was an error trying to load the movie.' ME.message];
                errordlg(msg);
                app.OpenButton.Text = 'Open';
                app.OpenButton.Enable = 'on';
                app.CancelButton.Enable = 'on';
                return
            end
            
            % Success. Update variables, and return control to main app.
            app.mov = mov;
            app.startFrame = start;
            app.stopFrame = stop;
            app.parent.sessionChosen(app)
        
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.cancelled = true;
            app.parent.sessionChosen(app);
        end

        % Value changed function: LocationDropDown
        function LocationDropDownValueChanged(app, event)
            app.MouseDropDown.Items = listMice(app.LocationDropDown.Value);
            app.DateDropDown.Items = listDates(app.MouseDropDown.Value, ...
                                               app.LocationDropDown.Value);
            app.SessionDropDown.Items = listExpNums(app.MouseDropDown.Value, ...
                                                    app.DateDropDown.Value, ...
                                                    app.LocationDropDown.Value);
            
        end

        % Close request function: SelectSessionUIFigure
        function SelectSessionUIFigureCloseRequest(app, event)
            app.cancelled = true;
            delete(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SelectSessionUIFigure = uifigure;
            app.SelectSessionUIFigure.Name = 'Select Session';
            app.SelectSessionUIFigure.CloseRequestFcn = createCallbackFcn(app, @SelectSessionUIFigureCloseRequest, true);
            app.MouseDropDownLabel = uilabel(app.SelectSessionUIFigure);
            app.MouseDropDownLabel.Text = 'Mouse';
            app.MouseDropDown = uidropdown(app.SelectSessionUIFigure);
            app.MouseDropDown.ValueChangedFcn = createCallbackFcn(app, @MouseDropDownValueChanged, true);
            app.DateDropDownLabel = uilabel(app.SelectSessionUIFigure);
            app.DateDropDownLabel.Text = 'Date';
            app.DateDropDown = uidropdown(app.SelectSessionUIFigure);
            app.DateDropDown.ValueChangedFcn = createCallbackFcn(app, @DateDropDownValueChanged, true);
            app.SessionDropDownLabel = uilabel(app.SelectSessionUIFigure);
            app.SessionDropDownLabel.Text = 'Session';
            app.SessionDropDown = uidropdown(app.SelectSessionUIFigure);
            app.OpenButton = uibutton(app.SelectSessionUIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.CancelButton = uibutton(app.SelectSessionUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.FramesEditFieldLabel = uilabel(app.SelectSessionUIFigure);
            app.FramesEditFieldLabel.Text = 'Frames';
            app.FramesEditField = uieditfield(app.SelectSessionUIFigure, 'text');
            app.LocationDropDownLabel = uilabel(app.SelectSessionUIFigure);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.SelectSessionUIFigure);
            app.LocationDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationDropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = OpenSession(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SelectSessionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SelectSessionUIFigure)
        end
    end
end